classdef btl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        checkdataoutputLabel        matlab.ui.control.Label
        checkdatainputLabel         matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        UITable2                    matlab.ui.control.Table
        runButton                   matlab.ui.control.Button
        savefilenameEditField       matlab.ui.control.EditField
        savefilenameEditFieldLabel  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        inputButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: inputButton
        function inputButtonPushed(app, event)
            global fullpath
            [filename,pathname]=uigetfile('*.csv','select file');
            fullpath=fullfile(pathname,filename);
            mat=readmatrix(fullpath);
            app.UITable.Data=mat(:,2:end-1);
            
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            global fullpath
            save_location=app.savefilenameEditField.Value;
            disp(save_location)
            % % try
                
                path = pyrunfile("ml.py","result",data_path=fullpath,out_path=save_location);
                dir_result=what(string(path));
                path_result=fullfile(dir_result.path,string(path));
                disp(path_result)
                mat=readmatrix(path_result);
                app.UITable2.Data=mat(:,2:end);
            % % catch 
            % % end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.inputButton = uibutton(app.UIFigure, 'push');
            app.inputButton.ButtonPushedFcn = createCallbackFcn(app, @inputButtonPushed, true);
            app.inputButton.Text = 'input';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.savefilenameEditFieldLabel = uilabel(app.UIFigure);
            app.savefilenameEditFieldLabel.Text = 'save file name';
            app.savefilenameEditField = uieditfield(app.UIFigure, 'text');
            app.runButton = uibutton(app.UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run ';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logoImage_1.png');
            app.checkdatainputLabel = uilabel(app.UIFigure);
            app.checkdatainputLabel.Text = 'check data input';
            app.checkdataoutputLabel = uilabel(app.UIFigure);
            app.checkdataoutputLabel.Text = 'check data output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = btl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end